classdef multiplication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Accuracy0Label        matlab.ui.control.Label
        Incorrect0Label       matlab.ui.control.Label
        Correct0Label         matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        CheckButton           matlab.ui.control.Button
        AnswerEditField       matlab.ui.control.NumericEditField
        AnswerEditFieldLabel  matlab.ui.control.Label
        DigitsSpinner         matlab.ui.control.Spinner
        DigitsSpinnerLabel    matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        digits = 2;
        num1 = 0;
        num2 = 0;
        correct = 0;
        incorrect = 0;
        accuracy = 0;
        accuracys = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            answer = app.num1 * app.num2;

            % Check that there isn't a transition going on
            if app.Label.Text == "Incorrect" || app.Label.Text == "Correct" || app.Label.Text == sprintf("%d",answer)
                return;
            end

            % Check if screen is blank
            if app.num1 ~= 0
                % Check if answer is right
                if app.AnswerEditField.Value == answer
                    app.Label.Text = sprintf("Correct");
                    pause(0.5);
                    app.correct = app.correct + 1;
                    app.Correct0Label.Text = sprintf("Correct: %d",app.correct);
                else
                    app.Label.Text = sprintf("Incorrect");
                    pause(0.5);
                    app.Label.Text = sprintf("%d",answer);
                    pause(0.5);
                    app.incorrect = app.incorrect + 1;
                    app.Incorrect0Label.Text = sprintf("Incorrect: %d",app.incorrect);
                end
            end
            % Calculate accuracy
            app.accuracy = app.correct/(max(app.correct + app.incorrect,1)) * 100;
            app.Accuracy0Label.Text = sprintf("Accuracy: %.1f%%",app.accuracy);
            
            % Update accuracys
            app.accuracys = [app.accuracys,app.accuracy];

            % Update graph
            hold(app.UIAxes,"off");
            cla(app.UIAxes);

            ylim(app.UIAxes,[0,100]);
            plot(app.UIAxes,0:(app.correct + app.incorrect),app.accuracys,"LineWidth",2,"Color","r");

            hold(app.UIAxes,"on");

            % Update numbers
            app.num1 = randi([10^(app.digits - 1),10^app.digits - 1]);
            app.num2 = randi([10^(app.digits - 1),10^app.digits - 1]);

            % Update graphic
            app.Label.Text = sprintf("%d x %d",app.num1,app.num2);

            % Clear answer field
            app.AnswerEditField.Value = 0;
        end

        % Value changing function: DigitsSpinner
        function DigitsSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.digits = changingValue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Accuracy')
            xlabel(app.UIAxes, 'Answers')
            ylabel(app.UIAxes, 'Percent')
            zlabel(app.UIAxes, 'Z')
            app.DigitsSpinnerLabel = uilabel(app.UIFigure);
            app.DigitsSpinnerLabel.Text = 'Digits';
            app.DigitsSpinner = uispinner(app.UIFigure);
            app.DigitsSpinner.ValueChangingFcn = createCallbackFcn(app, @DigitsSpinnerValueChanging, true);
            app.DigitsSpinner.Value = 2;
            app.AnswerEditFieldLabel = uilabel(app.UIFigure);
            app.AnswerEditFieldLabel.Text = 'Answer';
            app.AnswerEditField = uieditfield(app.UIFigure, 'numeric');
            app.CheckButton = uibutton(app.UIFigure, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Correct0Label = uilabel(app.UIFigure);
            app.Correct0Label.Text = 'Correct: 0';
            app.Incorrect0Label = uilabel(app.UIFigure);
            app.Incorrect0Label.Text = 'Incorrect: 0';
            app.Accuracy0Label = uilabel(app.UIFigure);
            app.Accuracy0Label.Text = 'Accuracy: 0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = multiplication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end