classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TermsLabel                    matlab.ui.control.Label
        ApproximationLabel            matlab.ui.control.Label
        CenterofApproximationSpinner  matlab.ui.control.Spinner
        CenterofApproximationSpinnerLabel  matlab.ui.control.Label
        XValueSpinner                 matlab.ui.control.Spinner
        XValueSpinnerLabel            matlab.ui.control.Label
        FunctionsDropDown             matlab.ui.control.DropDown
        FunctionsDropDownLabel        matlab.ui.control.Label
        AddTermButton                 matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description

        yval = 0;
        f = 0;
        curf = 0;
        term = 0;
        factorial = 1;
        sums = [0];
    end
    
    methods (Access = private)
        
        function reset(app)
            syms x;
            app.yval = 0;
            app.term = 0;
            app.factorial = 1;
            app.sums = [0];
            app.ApproximationLabel.Text = "Approximation:";
            app.TermsLabel.Text = "Terms:";
            
            if app.f == 0
                app.f = sin(x);
            end
            app.curf = app.f;
            
            cla(app.UIAxes);
            fplot(app.UIAxes,app.f,[0,10]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddTermButton
        function ButtonPushed(app, event)
            syms x;

            % Ensure that there are default values
            if app.f == 0
                app.FunctionsDropDownValueChanged("sin(x)");
            end

            % Get values of center of approximation and x value
            xval = app.XValueSpinner.Value;
            center = app.CenterofApproximationSpinner.Value;

            if xval == center
                return;
            end

            % Calculate current term
            numerator = double(subs(app.curf,x,center));

            cur = (numerator/app.factorial) * (xval - center)^app.term;

            app.term = app.term + 1;
            app.TermsLabel.Text = sprintf("Terms: %d",app.term);

            app.yval = app.yval + cur;
            app.ApproximationLabel.Text = sprintf("Approximation: %.5f",app.yval);

            app.sums = [app.sums,app.yval]; % Add current y value to sums
            % Update for next term
            app.curf = diff(app.curf);
            app.factorial = app.factorial * app.term;

            % Graph update
            cla(app.UIAxes);
            fplot(app.UIAxes,app.f,[0,10]);
            hold(app.UIAxes,"on");
            
            steps = abs(xval - center)/app.term;
            if xval < center
                steps = steps * -1;
            end

            xterms = center:steps:xval;

            plot(app.UIAxes,xterms,app.sums,"Color","r");
            legend(app.UIAxes,"Actual","Approximation");

        end

        % Clicked callback: FunctionsDropDown
        function FunctionsDropDownValueChanged(app, event)
            value = app.FunctionsDropDown.Value;
            syms x;
            if value == "sin(x)"
                app.f = sin(x);
            elseif value == "cos(x)"
                app.f = cos(x);
            elseif value == "e^x"
                app.f = exp(x);
            elseif value == "3x^2 + x"
                app.f = 3 * x^2 + x;
            elseif value == "sin^2(x)"
                app.f = sin(x)^2;
            end
            app.curf = app.f;
            app.reset();
            fplot(app.UIAxes,app.f,[0,10]);
        end

        % Value changed function: XValueSpinner
        function XValueSpinnerValueChanged(app, event)
            app.reset();
        end

        % Value changed function: CenterofApproximationSpinner
        function CenterofApproximationSpinnerValueChanged(app, event)
            app.reset();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Taylor Series Approximation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AddTermButton = uibutton(app.UIFigure, 'push');
            app.AddTermButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.AddTermButton.Text = 'Add Term';
            app.FunctionsDropDownLabel = uilabel(app.UIFigure);
            app.FunctionsDropDownLabel.Text = 'Functions';
            app.FunctionsDropDown = uidropdown(app.UIFigure);
            app.FunctionsDropDown.Items = {'sin(x)', 'cos(x)', 'e^x', '3x^2 + x', 'sin^2(x)'};
            app.FunctionsDropDown.ClickedFcn = createCallbackFcn(app, @FunctionsDropDownValueChanged, true);
            app.FunctionsDropDown.Value = 'sin(x)';
            app.XValueSpinnerLabel = uilabel(app.UIFigure);
            app.XValueSpinnerLabel.Text = 'X Value';
            app.XValueSpinner = uispinner(app.UIFigure);
            app.XValueSpinner.ValueChangedFcn = createCallbackFcn(app, @XValueSpinnerValueChanged, true);
            app.XValueSpinner.Value = 1;
            app.CenterofApproximationSpinnerLabel = uilabel(app.UIFigure);
            app.CenterofApproximationSpinnerLabel.Text = 'Center of Approximation';
            app.CenterofApproximationSpinner = uispinner(app.UIFigure);
            app.CenterofApproximationSpinner.ValueChangedFcn = createCallbackFcn(app, @CenterofApproximationSpinnerValueChanged, true);
            app.ApproximationLabel = uilabel(app.UIFigure);
            app.ApproximationLabel.Text = 'Approximation: ';
            app.TermsLabel = uilabel(app.UIFigure);
            app.TermsLabel.Text = 'Terms: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end