classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ResetButton                   matlab.ui.control.Button
        ActualLabel                   matlab.ui.control.Label
        CertaintyLabel                matlab.ui.control.Label
        MaxErrorLabel                 matlab.ui.control.Label
        TermsLabel                    matlab.ui.control.Label
        ApproximationLabel            matlab.ui.control.Label
        CenterofApproximationSpinner  matlab.ui.control.Spinner
        CenterofApproximationSpinnerLabel  matlab.ui.control.Label
        XValueSpinner                 matlab.ui.control.Spinner
        XValueSpinnerLabel            matlab.ui.control.Label
        FunctionsDropDown             matlab.ui.control.DropDown
        FunctionsDropDownLabel        matlab.ui.control.Label
        AddTermButton                 matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description

        center = 0;
        xval = 0;
        actualy = 0;
        yval = 0;
        f = 0;
        curf = 0;
        term = 0;
        factorial = 1;
        sums = [0];
    end
    
    methods (Access = private)
        
        function reset(app)
            syms x;
            app.xval = app.XValueSpinner.Value;
            app.center = app.CenterofApproximationSpinner.Value;
            app.yval = 0;
            app.term = 0;
            app.factorial = 1;
            app.sums = [0];
            
            app.TermsLabel.Text = "Terms:";
            
            if app.f == 0
                app.f = sin(x);
            end
            app.curf = app.f;

            app.actualy = double(subs(app.f,x,app.XValueSpinner.Value));

            % Reset Labels
            app.ApproximationLabel.Text = "Approximation:";
            app.ActualLabel.Text = sprintf("Actual: %.8f",app.actualy);
            app.TermsLabel.Text = sprintf("Terms: ");
            app.MaxErrorLabel.Text = sprintf("Max Error: ");
            app.CertaintyLabel.Text = sprintf("Certainty: ");

            hold(app.UIAxes,"off");

            cla(app.UIAxes);

            fplot(app.UIAxes,app.f,"LineWidth",2);

            hold(app.UIAxes,"on");

            legend(app.UIAxes,"Actual Function");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddTermButton
        function ButtonPushed(app, event)
            syms x;

            % Ensure that there are default values
            if app.f == 0
                app.FunctionsDropDownValueChanged("sin(x)");
            end

            % Get values of center of approximation and x value
            app.xval = app.XValueSpinner.Value;
            app.center = app.CenterofApproximationSpinner.Value;

            if app.xval == app.center
                return;
            end

            % Calculate current term
            numerator = double(subs(app.curf,x,app.center));

            cur = (numerator/app.factorial) * (app.xval - app.center)^app.term;

            app.term = app.term + 1;
            app.TermsLabel.Text = sprintf("Terms: %d",app.term);

            app.yval = app.yval + cur;
            app.ApproximationLabel.Text = sprintf("Approximation: %.8f",app.yval);

            app.sums = [app.sums,app.yval]; % Add current y value to sums

            % Update for next term
            app.curf = diff(app.curf);
            app.factorial = app.factorial * app.term;

            % Graph update
            hold(app.UIAxes,"off");
            cla(app.UIAxes);
            
            fplot(app.UIAxes,app.f,"LineWidth",2); % Plot actual function

            difference = abs(app.center-app.xval);
            xlim(app.UIAxes,[min(app.center,app.xval) - difference,max(app.center,app.xval) + difference]);
            
            difference2 = 0.5 * (max(app.sums) - min(app.sums));

            if difference2 > 0
                ylim(app.UIAxes,[min(app.sums) - difference2,max(app.sums) + difference2])
            end
            

            hold(app.UIAxes,"on");

            steps = abs(app.xval - app.center)/app.term;
            if app.xval < app.center
                steps = steps * -1;
            end

            xterms = app.center:steps:app.xval;
            
            % Current approximation line
            plot(app.UIAxes,xterms,app.sums,"Color","r","LineWidth",2);            

            % Approximation over multiple terms
            plot(app.UIAxes,app.xval,app.yval,"yo","MarkerSize",4,"MarkerFaceColor","y");

            % Center of approximation
            plot(app.UIAxes,app.center,0,"bo","MarkerSize",4,"MarkerFaceColor","b");

            % Actual value
            plot(app.UIAxes,app.xval,app.actualy,"go","MarkerSize",4,"MarkerFaceColor","g");

            % Legend
            legend(app.UIAxes,"Actual function","Approx. line","Current Approx.","Center of Approx.","Actual value");
            
            % Enable grid
            grid(app.UIAxes,"on");

            % Calculate the maximum error

            % Solve for M in Lagrange form
            nextf = abs(diff(app.curf)); % Derivative of current function
            nextfactorial = app.factorial * (app.term + 1);

            zeros = solve(diff(nextf),x); % Solve for potential local maxima

            candidates = double(subs(nextf,x,zeros));
            candidates = [candidates,double(subs(nextf,x,app.center))]; % First bound
            candidates = [candidates,double(subs(nextf,x,app.xval))]; % Second bound
            M = max(candidates);
            disp(zeros);

            Maxerror = M/nextfactorial * (abs(app.xval - app.center))^(app.term + 1);

            app.MaxErrorLabel.Text = sprintf("Max Error: %.2e",Maxerror);

            % Calculate decimal points of certainty
            certainty =  floor(-1 * log10(2 * Maxerror));

            certainty = max(certainty,0);

            % Update certainty
            app.CertaintyLabel.Text = sprintf("Certainty: %d",certainty);
        end

        % Clicked callback: FunctionsDropDown
        function FunctionsDropDownValueChanged(app, event)
            value = app.FunctionsDropDown.Value;
            syms x;
            if value == "sin(x)"
                app.f = sin(x);
            elseif value == "cos(x)"
                app.f = cos(x);
            elseif value == "e^x"
                app.f = exp(x);
            elseif value == "3x^2 + x"
                app.f = 3 * x^2 + x;
            elseif value == "sin^2(x)"
                app.f = sin(x)^2;
            end
            app.curf = app.f;
            app.reset();            
        end

        % Value changed function: XValueSpinner
        function XValueSpinnerValueChanged(app, event)
            app.reset();
        end

        % Value changed function: CenterofApproximationSpinner
        function CenterofApproximationSpinnerValueChanged(app, event)
            app.reset();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.reset();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Taylor Series Approximation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AddTermButton = uibutton(app.UIFigure, 'push');
            app.AddTermButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.AddTermButton.Text = 'Add Term';
            app.FunctionsDropDownLabel = uilabel(app.UIFigure);
            app.FunctionsDropDownLabel.Text = 'Functions';
            app.FunctionsDropDown = uidropdown(app.UIFigure);
            app.FunctionsDropDown.Items = {'sin(x)', 'cos(x)', 'e^x', '3x^2 + x', 'sin^2(x)'};
            app.FunctionsDropDown.ClickedFcn = createCallbackFcn(app, @FunctionsDropDownValueChanged, true);
            app.FunctionsDropDown.Value = 'sin(x)';
            app.XValueSpinnerLabel = uilabel(app.UIFigure);
            app.XValueSpinnerLabel.Text = 'X Value';
            app.XValueSpinner = uispinner(app.UIFigure);
            app.XValueSpinner.ValueChangedFcn = createCallbackFcn(app, @XValueSpinnerValueChanged, true);
            app.XValueSpinner.Value = 1;
            app.CenterofApproximationSpinnerLabel = uilabel(app.UIFigure);
            app.CenterofApproximationSpinnerLabel.Text = 'Center of Approximation';
            app.CenterofApproximationSpinner = uispinner(app.UIFigure);
            app.CenterofApproximationSpinner.ValueChangedFcn = createCallbackFcn(app, @CenterofApproximationSpinnerValueChanged, true);
            app.ApproximationLabel = uilabel(app.UIFigure);
            app.ApproximationLabel.Text = 'Approximation: ';
            app.TermsLabel = uilabel(app.UIFigure);
            app.TermsLabel.Text = 'Terms: ';
            app.MaxErrorLabel = uilabel(app.UIFigure);
            app.MaxErrorLabel.Text = 'Max Error:';
            app.CertaintyLabel = uilabel(app.UIFigure);
            app.CertaintyLabel.Text = 'Certainty:';
            app.ActualLabel = uilabel(app.UIFigure);
            app.ActualLabel.Text = 'Actual: ';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end